classdef KMeansDataVisualisation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %KCluster
        depth % Description: enables the depth to be stored.
        dIndex % Description: enables the depth index to be stored.
        kIndex % Description: enables the kindex values to be stored.
        kX % Description: enables the x values to be stored.
        kY % Description: enables the y values to be stored.

        %clusters
        cDIndex % Cluster depth values
        cKIndex % Cluster data owners of which k values
        studentNumber % stores student number values
        cX % stores cluster x values
        cY % stores cluster y values
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            kcluster = readtable("cvs/KCluster.csv");
            app.depth = unique(kcluster.D);
            app.dIndex = kcluster{:, "D"};
            app.kIndex = kcluster{:, "K"};
            app.kX = kcluster{:, "X"};
            app.kY = kcluster{:, "Y"};

            % default
            app.SliderLabel.Text = "Depth";

            app.Slider.Value = 0;
            app.Slider.Limits = [0 size(app.depth, 1)-1];
            app.Slider.MajorTicks = 0:size(app.depth, 1)-1;

            % set up the graph
            cluster = readtable("cvs/Cluster.csv");
            app.cDIndex = cluster{:, "D"};
            app.cKIndex = cluster{:, "K"};
            app.studentNumber = string(cluster{:, 3});
            app.cX = cluster{:, "X"};
            app.cY = cluster{:, "Y"};

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KMeansDataVisualisation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end